classdef TUSC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TUSCUIFigure                  matlab.ui.Figure
        RightPanel                    matlab.ui.container.Panel
        RefreshfromdeviceButton       matlab.ui.control.Button
        StatusmonitoringSwitch        matlab.ui.control.Switch
        StatusmonitoringSwitchLabel   matlab.ui.control.Label
        PanicButton                   matlab.ui.control.Button
        SoundvolumeSpinner            matlab.ui.control.Spinner
        SoundvolumeSpinnerLabel       matlab.ui.control.Label
        TriggermodeSpinner            matlab.ui.control.Spinner
        TriggermodeSpinnerLabel       matlab.ui.control.Label
        AboutButton                   matlab.ui.control.Button
        CenterPanel                   matlab.ui.container.Panel
        StatusPanel                   matlab.ui.container.Panel
        ConnectionidleLabel           matlab.ui.control.Label
        SonicatingLamp_2              matlab.ui.control.Lamp
        Label                         matlab.ui.control.Label
        SonicatingLamp_2Label         matlab.ui.control.Label
        SonicateSwitch                matlab.ui.control.Switch
        TranscranialUltrasoundControlLabel  matlab.ui.control.Label
        TUSCLabel                     matlab.ui.control.Label
        SonicateSwitchLabel           matlab.ui.control.Label
        ConnectedLamp                 matlab.ui.control.Lamp
        ConnectedLampLabel            matlab.ui.control.Label
        WattsGauge                    matlab.ui.control.Gauge
        WattsGaugeLabel               matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        LeftPanel                     matlab.ui.container.Panel
        FrequencyHzSpinner            matlab.ui.control.Spinner
        FrequencyHzSpinnerLabel       matlab.ui.control.Label
        CurrentprofileEditField       matlab.ui.control.EditField
        CurrentprofileEditFieldLabel  matlab.ui.control.Label
        ProfilesListBox               matlab.ui.control.ListBox
        ProfilesLabel                 matlab.ui.control.Label
        GlobalpowerWSpinner           matlab.ui.control.Spinner
        GlobalpowerWSpinnerLabel      matlab.ui.control.Label
        BurstlengthmsSpinner          matlab.ui.control.Spinner
        BurstlengthmsSpinnerLabel     matlab.ui.control.Label
        BurstperiodmsSpinner          matlab.ui.control.Spinner
        BurstperiodmsSpinnerLabel     matlab.ui.control.Label
        TimermsSpinner                matlab.ui.control.Spinner
        TimermsSpinnerLabel           matlab.ui.control.Label
        RampmodeDropDown              matlab.ui.control.DropDown
        RampmodeDropDownLabel         matlab.ui.control.Label
        RamplengthmsSpinner           matlab.ui.control.Spinner
        RamplengthmsSpinnerLabel      matlab.ui.control.Label
        FocusmmSpinner_2              matlab.ui.control.Spinner
        FocusmmSpinner_2Label         matlab.ui.control.Label
        FFLabel                       matlab.ui.control.Label
        PDLabel                       matlab.ui.control.Label
        SDLabel                       matlab.ui.control.Label
        PRFHzLabel                    matlab.ui.control.Label
        ViewlogButton                 matlab.ui.control.Button
        ClearlogButton                matlab.ui.control.Button
        RenameprofileButton           matlab.ui.control.Button
        DeleteprofileButton           matlab.ui.control.Button
        LoadprofileButton             matlab.ui.control.Button
        SaveprofileButton             matlab.ui.control.Button
        EnforcepowerlimitsCheckBox    matlab.ui.control.CheckBox
    end

    properties (Access = public)
        s = []
        sonicating = false
        writingToTPO = false
    end
    methods
        function v = get.sonicating(app)
            v = app.sonicating;
        end
        function set.sonicating(app,v)
            if v
                app.TimermsSpinner.Enable = "off";
                app.SonicatingLamp_2.Color = 'g';
                app.SonicatingLamp_2.Enable = "on";
                app.WattsGauge.Enable = "on";
                % app.WattsGauge.Value = app.GlobalpowerWSpinner.Value; % update this! to P_actual or ISPPA/ISPTA
                app.ConnectionidleLabel.Text = 'Currently sonicating';
            else
                app.SonicatingLamp_2.Color = 'r';
                app.SonicatingLamp_2.Enable = "off";
                app.TimermsSpinner.Enable = "on";
                app.WattsGauge.Enable = "off";
                % app.WattsGauge.Value = 0;
                app.ConnectionidleLabel.Text = 'Connection idle';
            end
            app.sonicating = v;
        end
    end
    methods (Access = private)
        
        function results = configureTUSC(app)
            initialize(app);
        end

        function attemptAutoMonitoringLoop(app)
            try
                value = app.StatusmonitoringSwitch.Value;
            catch
                value = "";
            end
            while value == "Auto"
                pause(0.001);
                app.readDeviceStatus();
                try
                    value = app.StatusmonitoringSwitch.Value;
                catch
                end
            end
        end

        function readDeviceStatus(app)
            try
                if app.writingToTPO
                    return;
                end
                if ~isempty(app.s)
                    app.ConnectedLamp.Color = 'g';
                    app.ConnectedLamp.Enable = "on";
    
                        app.sonicating = logical(writeCheck(app.s,'SYSTEMSTATE?',[]));
                        app.BurstlengthmsSpinner.Value = writeCheck(app.s,'BURST?',[]);
                        app.BurstperiodmsSpinner.Value = writeCheck(app.s,'PERIOD?',[]);
                        if ~app.sonicating
                            app.TimermsSpinner.Value = writeCheck(app.s,'TIMER?',[]);
                        end
                        app.RampmodeDropDown.ValueIndex = writeCheck(app.s,'RAMPMODE?',[]) + 1;
                        app.RamplengthmsSpinner.Value = writeCheck(app.s,'RAMPLENGTH?',[]);
                        app.FocusmmSpinner_2.Value = writeCheck(app.s,'FOCUS?',[]);
                        app.GlobalpowerWSpinner.Value = writeCheck(app.s,'GLOBALPOWER?',[]);
                        app.EnforcepowerlimitsCheckBox.Value = logical(writeCheck(app.s,'ENFORCELIMITS?',[]));
                        app.TriggermodeSpinner.Value = writeCheck(app.s,'TRIGGERMODE?',[]);
                        app.SoundvolumeSpinner.Value = writeCheck(app.s,'VOLUME?',[]);
    
                        %writeCheck(app.s,'POWER?',[])
                        % = app.sendCommand(sprintf('OUTON?'),[]);
    
                else
                    app.ConnectedLamp.Color = 'r';
                    app.ConnectedLamp.Enable = "off";
                end
            catch
            end
        end

        function result = sendCommand(app,command,expectedOutput,status)
            if nargin < 4 || isempty(status)
                status = 'Sending command...';
            else
                status = [status,'...'];
            end
            app.writingToTPO = true;
            result = [];
            app.ConnectionidleLabel.Text = status;
            if ~isempty(app.s)
                result = writeCheck(app.s,command,expectedOutput);
                if isempty(result)
                    status = 'Could not connect to TPO';
                else
                    if ~app.sonicating
                        status = 'Connection idle';
                    else
                        status = 'Currently sonicating';
                    end
                end
            else
                status = 'Could not connect to TPO';
            end
            app.ConnectionidleLabel.Text = status;
            app.writingToTPO = false;
            app.readDeviceStatus();
        end
        
        function s = openConnection(app)
            try
                freeports = serialportlist("available")
                % Open a serial connection at 115200 baud, 8 data bits, no parity, with
                % no handshaking:
                s = serialport(freeports(1),115200,"Parity","none","DataBits",8,"FlowControl","none",'Timeout',3);
                app.ConnectionidleLabel.Text = 'Attempting to connect to TPO...';
                configureTerminator(s,"CR");
    
                firmwareversion = s.readline();
                if ~isempty(firmwareversion)
                    fprintf('Successfully opened connection with device: %s\n',firmwareversion);
                    connstatus = 'Connected to TPO';
                    app.s = s;
                else
                    connstatus = 'Could not connect to TPO';
                    app.s = [];
                end
                pause(2);
                app.ConnectionidleLabel.Text = connstatus;
                app.readDeviceStatus();
                
                % % Configure TPO to accept advanced commands:
                sendCommand(app,'LOCAL=0','Local: 0');
                sendCommand(app,'VOLUME=0','Sound volume: 0','Setting volume');
            catch
            end
            % 
            % writeCheck(s,'TRIGGERMODE=0','Trigger Mode: 0');
            % 
            % % writeCheck(s,'BURST=180000','Burst length: 180.000 ms');
            % % writeCheck(s,'PERIOD=500000','Burst period: 500.000 ms');
            % writeCheck(s,'BURST=80','Burst length: 0.080 ms');
            % writeCheck(s,'PERIOD=20000','Burst period: 20.000 ms');
            % % writeCheck(s,'BURST=5000','Burst length: 5.000 ms');
            % 
            % % writeCheck(s,'GLOBALPOWER=500','Global power: 0.500');
            % 
            %     writeCheck(s,'TIMER=0','Timer: 0.000 ms'); % 0 constant for calibration
            % %     writeCheck(s,'TIMER=500000','Timer: 500.000 ms'); % 500ms for pulse
            % %     writeCheck(s,'RAMPMODE=4','Ramp mode: 4'); % exponential ramp
            %     writeCheck(s,'RAMPMODE=0','Ramp mode: 0'); % no ramp
            %     writeCheck(s,'RAMPLENGTH=4000','Ramp length: 4.000 ms'); % exponential ramp
            %     writeCheck(s,'FOCUS=41900','Focus: 41.900 mm'); % UNDOCUMENTED!!!!!!
            % % writeCheck(s,'FOCUS=39200','Focus: 39.200 mm'); % UNDOCUMENTED!!!!!!
            % %     writeCheck(s,'ENFORCELIMITS=0','Enforce Power Limits: 0'); % TURN OFF SAFETY!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!
            %     writeCheck(s,'ENFORCELIMITS=1','Enforce Power Limits: 1'); % TURN OFF SAFETY!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!
            % %      writeCheck(s,'GLOBALPOWER=25000','Global power: 25.000');
            % %     writeCheck(s,'GLOBALPOWER=10000','Global power: 10.000');
            % %     writeCheck(s,'GLOBALPOWER=21250','Global power: 21.250');
            %     writeCheck(s,'GLOBALPOWER=12690','Global power: 12.690');
            % 
            % %     writeCheck(s,'START','Treatment started');
            % %     writeCheck(s,'ABORT','Treatment aborted');
            % 
            % % Sanguinetti params:
            % 
            % % writeCheck(s,'PERIOD=120000000','Burst period: 120000.000 ms');
            % % writeCheck(s,'BURST=600000','Burst length: 600.000 ms');
            % % writeCheck(s,'GLOBALPOWER=4230','Global power: 4.230');
end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.configureTUSC;
            s = app.openConnection()

            % app.StatusmonitoringSwitch.Value = "Auto";
            % app.StatusmonitoringSwitch.Value = "Manual";
            % app.StatusmonitoringSwitch.Value = "Auto";
            app.attemptAutoMonitoringLoop();
        end

        % Button pushed function: AboutButton
        function AboutButtonPushed(app, event)
            about_TUSC;
        end

        % Value changed function: ProfilesListBox
        function ProfilesListBoxValueChanged(app, event)
            %value = app.ProfilesListBox.Value;
        end

        % Button pushed function: LoadprofileButton
        function LoadprofileButtonPushed(app, event)
            app.CurrentprofileEditField.Value = app.ProfilesListBox.Value;
        end

        % Value changed function: BurstlengthmsSpinner
        function BurstlengthmsSpinnerValueChanged(app, event)

            value = app.BurstlengthmsSpinner.Value;
            result = app.sendCommand(sprintf('BURST=%d',value*1000),sprintf('Burst length: %3.3f ms',value));
            if strcmpi(result,'VALUE ERROR')
                event.Source.Value = event.PreviousValue;
            end
        end

        % Value changed function: BurstperiodmsSpinner
        function BurstperiodmsSpinnerValueChanged(app, event)
            value = app.BurstperiodmsSpinner.Value;
            result = app.sendCommand(sprintf('PERIOD=%d',value*1000),sprintf('Burst period: %3.3f ms',value));
            if strcmpi(result,'VALUE ERROR')
                event.Source.Value = event.PreviousValue;
            end
        end

        % Value changed function: TimermsSpinner
        function TimermsSpinnerValueChanged(app, event)
            value = app.TimermsSpinner.Value;
            result = app.sendCommand(sprintf('TIMER=%d',value*1000),sprintf('Timer: %3.3f ms',value));
            if strcmpi(result,'VALUE ERROR')
                event.Source.Value = event.PreviousValue;
            end
        end

        % Value changed function: RampmodeDropDown
        function RampmodeDropDownValueChanged(app, event)
            index = app.RampmodeDropDown.ValueIndex - 1;
            result = writeCheck(app.s,sprintf('RAMPMODE=%d',index),sprintf('Ramp mode: %d',index));
        end

        % Value changed function: RamplengthmsSpinner
        function RamplengthmsSpinnerValueChanged(app, event)
            value = app.RamplengthmsSpinner.Value;
            result = writeCheck(app.s,sprintf('RAMPLENGTH=%d',value*1000),sprintf('Ramp length: %d',value));
            if strcmpi(result,'VALUE ERROR')
                event.Source.Value = event.PreviousValue;
            end
        end

        % Value changed function: FocusmmSpinner_2
        function FocusmmSpinner_2ValueChanged(app, event)
            value = app.FocusmmSpinner_2.Value;
        end

        % Value changed function: GlobalpowerWSpinner
        function GlobalpowerWSpinnerValueChanged(app, event)
            value = app.GlobalpowerWSpinner.Value;
            result = app.sendCommand(sprintf('GLOBALPOWER=%d',value*1000),sprintf('Global power: %3.3f',value));
            if strcmpi(result,'VALUE ERROR')
                event.Source.Value = event.PreviousValue;
            end
        end

        % Value changed function: EnforcepowerlimitsCheckBox
        function EnforcepowerlimitsCheckBoxValueChanged(app, event)
            value = app.EnforcepowerlimitsCheckBox.Value;
            if value == false
                answer = questdlg('This will remove safety limits. Are you sure you wish to continue?', ...
	            'Warning','Yes','No, take me back','No, take me back');
                if strcmpi(answer,'No, take me back')
                    event.Source.Value = event.PreviousValue;
                    return;
                end
            end
            result = app.sendCommand(sprintf('ENFORCELIMITS=%d',double(value)),sprintf('Enforce Power Limits: %d',double(value)));
        end

        % Callback function
        function SonicateSwitchValueChanged(app, event)
            
        end

        % Value changed function: TriggermodeSpinner
        function TriggermodeSpinnerValueChanged(app, event)
            value = app.TriggermodeSpinner.Value;
            
        end

        % Value changed function: SoundvolumeSpinner
        function SoundvolumeSpinnerValueChanged(app, event)
            value = app.SoundvolumeSpinner.Value;
            
        end

        % Button pushed function: PanicButton
        function PanicButtonPushed(app, event)
            app.SonicateSwitch.Value = "Off";
            app.SonicateSwitch.ValueChangedFcn(app,[]);
        end

        % Button pushed function: SaveprofileButton
        function SaveprofileButtonPushed(app, event)
            
        end

        % Button pushed function: DeleteprofileButton
        function DeleteprofileButtonPushed(app, event)
            
        end

        % Button pushed function: RenameprofileButton
        function RenameprofileButtonPushed(app, event)
            
        end

        % Close request function: TUSCUIFigure
        function TUSCUIFigureCloseRequest(app, event)

            delete(app)
        end

        % Value changed function: SonicateSwitch
        function SonicateSwitchValueChanged2(app, event)
            value = app.SonicateSwitch.Value;
            if strcmpi(value,"On")
                %app.sonicating = true;
                app.sendCommand('START','Treatment started');
            else
                app.sendCommand('ABORT','Treatment aborted');
                %app.sonicating = false;
            end
            app.readDeviceStatus();
            if app.sonicating
                app.WattsGauge.Value = app.GlobalpowerWSpinner.Value;
            else
                app.WattsGauge.Value = 0;
            end
        end

        % Value changed function: StatusmonitoringSwitch
        function StatusmonitoringSwitchValueChanged(app, event)
            if app.StatusmonitoringSwitch.Value == "Manual"
                app.RefreshfromdeviceButton.Enable = "on";
            else
                app.RefreshfromdeviceButton.Enable = "off";
            end
            app.attemptAutoMonitoringLoop();
        end

        % Button pushed function: RefreshfromdeviceButton
        function RefreshfromdeviceButtonPushed(app, event)
            app.readDeviceStatus();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TUSCUIFigure = uifigure('Visible', 'off');
            app.TUSCUIFigure.Name = 'TUSC';
            app.TUSCUIFigure.Icon = fullfile(pathToMLAPP, 'resources', 'elephanticon_rcalmus130x130editedwbody.png');
            app.TUSCUIFigure.CloseRequestFcn = createCallbackFcn(app, @TUSCUIFigureCloseRequest, true);
            app.LeftPanel = uipanel(app.TUSCUIFigure);
            app.LeftPanel.Enable = 'off';
            app.EnforcepowerlimitsCheckBox = uicheckbox(app.LeftPanel);
            app.EnforcepowerlimitsCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnforcepowerlimitsCheckBoxValueChanged, true);
            app.EnforcepowerlimitsCheckBox.Text = 'Enforce power limits';
            app.EnforcepowerlimitsCheckBox.Value = true;
            app.SaveprofileButton = uibutton(app.LeftPanel, 'push');
            app.SaveprofileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveprofileButtonPushed, true);
            app.SaveprofileButton.Text = 'Save profile';
            app.LoadprofileButton = uibutton(app.LeftPanel, 'push');
            app.LoadprofileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadprofileButtonPushed, true);
            app.LoadprofileButton.Text = 'Load profile';
            app.DeleteprofileButton = uibutton(app.LeftPanel, 'push');
            app.DeleteprofileButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteprofileButtonPushed, true);
            app.DeleteprofileButton.Text = 'Delete profile';
            app.RenameprofileButton = uibutton(app.LeftPanel, 'push');
            app.RenameprofileButton.ButtonPushedFcn = createCallbackFcn(app, @RenameprofileButtonPushed, true);
            app.RenameprofileButton.Text = 'Rename profile';
            app.ClearlogButton = uibutton(app.LeftPanel, 'push');
            app.ClearlogButton.Text = 'Clear log';
            app.ViewlogButton = uibutton(app.LeftPanel, 'push');
            app.ViewlogButton.Text = 'View log';
            app.PRFHzLabel = uilabel(app.LeftPanel);
            app.PRFHzLabel.Tooltip = {'Pulse repetition frequency (PRF)'};
            app.PRFHzLabel.Text = 'PRF (??? Hz)';
            app.SDLabel = uilabel(app.LeftPanel);
            app.SDLabel.Tooltip = {'Sonication duration (SD)'};
            app.SDLabel.Text = 'SD';
            app.PDLabel = uilabel(app.LeftPanel);
            app.PDLabel.Tooltip = {'Pulse duration (PD)'};
            app.PDLabel.Text = 'PD';
            app.FFLabel = uilabel(app.LeftPanel);
            app.FFLabel.Tooltip = {'Fundamental frequency (FF)'};
            app.FFLabel.Text = 'FF';
            app.FocusmmSpinner_2Label = uilabel(app.LeftPanel);
            app.FocusmmSpinner_2Label.Text = 'Focus (mm)';
            app.FocusmmSpinner_2 = uispinner(app.LeftPanel);
            app.FocusmmSpinner_2.Step = 0.5;
            app.FocusmmSpinner_2.ValueChangedFcn = createCallbackFcn(app, @FocusmmSpinner_2ValueChanged, true);
            app.RamplengthmsSpinnerLabel = uilabel(app.LeftPanel);
            app.RamplengthmsSpinnerLabel.Text = 'Ramp length (ms)';
            app.RamplengthmsSpinner = uispinner(app.LeftPanel);
            app.RamplengthmsSpinner.Step = 0.001;
            app.RamplengthmsSpinner.ValueDisplayFormat = '%.3f';
            app.RamplengthmsSpinner.ValueChangedFcn = createCallbackFcn(app, @RamplengthmsSpinnerValueChanged, true);
            app.RampmodeDropDownLabel = uilabel(app.LeftPanel);
            app.RampmodeDropDownLabel.Text = 'Ramp mode';
            app.RampmodeDropDown = uidropdown(app.LeftPanel);
            app.RampmodeDropDown.Items = {'No ramping', 'Linear', 'Tukey', 'Logarithmic', 'Exponential', 'Gaussian'};
            app.RampmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @RampmodeDropDownValueChanged, true);
            app.RampmodeDropDown.Value = 'No ramping';
            app.TimermsSpinnerLabel = uilabel(app.LeftPanel);
            app.TimermsSpinnerLabel.Text = 'Timer (ms)';
            app.TimermsSpinner = uispinner(app.LeftPanel);
            app.TimermsSpinner.ValueDisplayFormat = '%.3f';
            app.TimermsSpinner.ValueChangedFcn = createCallbackFcn(app, @TimermsSpinnerValueChanged, true);
            app.BurstperiodmsSpinnerLabel = uilabel(app.LeftPanel);
            app.BurstperiodmsSpinnerLabel.Text = 'Burst period (ms)';
            app.BurstperiodmsSpinner = uispinner(app.LeftPanel);
            app.BurstperiodmsSpinner.Step = 0.1;
            app.BurstperiodmsSpinner.ValueDisplayFormat = '%.3f';
            app.BurstperiodmsSpinner.ValueChangedFcn = createCallbackFcn(app, @BurstperiodmsSpinnerValueChanged, true);
            app.BurstlengthmsSpinnerLabel = uilabel(app.LeftPanel);
            app.BurstlengthmsSpinnerLabel.Text = 'Burst length (ms)';
            app.BurstlengthmsSpinner = uispinner(app.LeftPanel);
            app.BurstlengthmsSpinner.Step = 0.01;
            app.BurstlengthmsSpinner.ValueDisplayFormat = '%.3f';
            app.BurstlengthmsSpinner.ValueChangedFcn = createCallbackFcn(app, @BurstlengthmsSpinnerValueChanged, true);
            app.BurstlengthmsSpinner.Value = 0.08;
            app.GlobalpowerWSpinnerLabel = uilabel(app.LeftPanel);
            app.GlobalpowerWSpinnerLabel.Text = 'Global power (W)';
            app.GlobalpowerWSpinner = uispinner(app.LeftPanel);
            app.GlobalpowerWSpinner.Step = 0.1;
            app.GlobalpowerWSpinner.ValueDisplayFormat = '%.3f';
            app.GlobalpowerWSpinner.ValueChangedFcn = createCallbackFcn(app, @GlobalpowerWSpinnerValueChanged, true);
            app.ProfilesLabel = uilabel(app.LeftPanel);
            app.ProfilesLabel.Text = 'Profiles:';
            app.ProfilesListBox = uilistbox(app.LeftPanel);
            app.ProfilesListBox.ValueChangedFcn = createCallbackFcn(app, @ProfilesListBoxValueChanged, true);
            app.CurrentprofileEditFieldLabel = uilabel(app.LeftPanel);
            app.CurrentprofileEditFieldLabel.Text = 'Current profile:';
            app.CurrentprofileEditField = uieditfield(app.LeftPanel, 'text');
            app.FrequencyHzSpinnerLabel = uilabel(app.LeftPanel);
            app.FrequencyHzSpinnerLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzSpinner = uispinner(app.LeftPanel);
            app.FrequencyHzSpinner.Step = 0.01;
            app.FrequencyHzSpinner.ValueDisplayFormat = '%.3f';
            app.FrequencyHzSpinner.Value = 0.08;
            app.CenterPanel = uipanel(app.TUSCUIFigure);
            app.CenterPanel.Enable = 'off';
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Simulated output trace')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, {'Intensity'; '(W/cm^2)'})
            app.WattsGaugeLabel = uilabel(app.CenterPanel);
            app.WattsGaugeLabel.Enable = 'off';
            app.WattsGaugeLabel.Text = 'Watts';
            app.WattsGauge = uigauge(app.CenterPanel, 'circular');
            app.WattsGauge.Enable = 'off';
            app.ConnectedLampLabel = uilabel(app.CenterPanel);
            app.ConnectedLampLabel.Text = 'Connected';
            app.ConnectedLamp = uilamp(app.CenterPanel);
            app.ConnectedLamp.Enable = 'off';
            app.SonicateSwitchLabel = uilabel(app.CenterPanel);
            app.SonicateSwitchLabel.Text = 'Sonicate';
            app.TUSCLabel = uilabel(app.CenterPanel);
            app.TUSCLabel.Text = 'TUSC';
            app.TranscranialUltrasoundControlLabel = uilabel(app.CenterPanel);
            app.TranscranialUltrasoundControlLabel.Text = 'Transcranial Ultrasound Control';
            app.SonicateSwitch = uiswitch(app.CenterPanel, 'slider');
            app.SonicateSwitch.ValueChangedFcn = createCallbackFcn(app, @SonicateSwitchValueChanged2, true);
            app.SonicatingLamp_2Label = uilabel(app.CenterPanel);
            app.SonicatingLamp_2Label.Text = 'Sonicating';
            app.Label = uilabel(app.CenterPanel);
            app.Label.Text = '________________________________________________';
            app.SonicatingLamp_2 = uilamp(app.CenterPanel);
            app.SonicatingLamp_2.Enable = 'off';
            app.StatusPanel = uipanel(app.CenterPanel);
            app.StatusPanel.Title = 'Status';
            app.ConnectionidleLabel = uilabel(app.StatusPanel);
            app.ConnectionidleLabel.Text = 'Connection idle';
            app.RightPanel = uipanel(app.TUSCUIFigure);
            app.RightPanel.Enable = 'off';
            app.AboutButton = uibutton(app.RightPanel, 'push');
            app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButtonPushed, true);
            app.AboutButton.Text = 'About';
            app.TriggermodeSpinnerLabel = uilabel(app.RightPanel);
            app.TriggermodeSpinnerLabel.Text = 'Trigger mode';
            app.TriggermodeSpinner = uispinner(app.RightPanel);
            app.TriggermodeSpinner.ValueChangedFcn = createCallbackFcn(app, @TriggermodeSpinnerValueChanged, true);
            app.SoundvolumeSpinnerLabel = uilabel(app.RightPanel);
            app.SoundvolumeSpinnerLabel.Text = 'Sound volume';
            app.SoundvolumeSpinner = uispinner(app.RightPanel);
            app.SoundvolumeSpinner.ValueChangedFcn = createCallbackFcn(app, @SoundvolumeSpinnerValueChanged, true);
            app.PanicButton = uibutton(app.RightPanel, 'push');
            app.PanicButton.ButtonPushedFcn = createCallbackFcn(app, @PanicButtonPushed, true);
            app.PanicButton.Text = 'Panic';
            app.StatusmonitoringSwitchLabel = uilabel(app.RightPanel);
            app.StatusmonitoringSwitchLabel.Text = 'Status monitoring';
            app.StatusmonitoringSwitch = uiswitch(app.RightPanel, 'slider');
            app.StatusmonitoringSwitch.Items = {'Auto', 'Manual'};
            app.StatusmonitoringSwitch.ValueChangedFcn = createCallbackFcn(app, @StatusmonitoringSwitchValueChanged, true);
            app.StatusmonitoringSwitch.Value = 'Auto';
            app.RefreshfromdeviceButton = uibutton(app.RightPanel, 'push');
            app.RefreshfromdeviceButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshfromdeviceButtonPushed, true);
            app.RefreshfromdeviceButton.Enable = 'off';
            app.RefreshfromdeviceButton.Text = 'Refresh from device';
            % Show the figure after all components are created
            app.TUSCUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TUSC

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TUSCUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.TUSCUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TUSCUIFigure)
        end
    end
end