classdef TUSC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TUSCUIFigure                    matlab.ui.Figure
        RightPanel                      matlab.ui.container.Panel
        ViewlogButton                   matlab.ui.control.Button
        ClearlogButton                  matlab.ui.control.Button
        RefreshfromdeviceButton         matlab.ui.control.Button
        StatusmonitoringSwitch          matlab.ui.control.Switch
        StatusmonitoringSwitchLabel     matlab.ui.control.Label
        PanicButton                     matlab.ui.control.Button
        SoundvolumeSpinner              matlab.ui.control.Spinner
        SoundvolumeSpinnerLabel         matlab.ui.control.Label
        TriggermodeSpinner              matlab.ui.control.Spinner
        TriggermodeSpinnerLabel         matlab.ui.control.Label
        AboutButton                     matlab.ui.control.Button
        CenterPanel                     matlab.ui.container.Panel
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        Button_6                        matlab.ui.control.ToggleButton
        Button_5                        matlab.ui.control.ToggleButton
        xButton_2                       matlab.ui.control.ToggleButton
        InfButton_3                     matlab.ui.control.ToggleButton
        StatusPanel                     matlab.ui.container.Panel
        ConnectionidleLabel             matlab.ui.control.Label
        SonicatingLamp_2                matlab.ui.control.Lamp
        Label                           matlab.ui.control.Label
        SonicatingLamp_2Label           matlab.ui.control.Label
        SonicateSwitch                  matlab.ui.control.Switch
        TranscranialUltrasoundControlLabel  matlab.ui.control.Label
        TUSCLabel                       matlab.ui.control.Label
        SonicateSwitchLabel             matlab.ui.control.Label
        ConnectedLamp                   matlab.ui.control.Lamp
        ConnectedLampLabel              matlab.ui.control.Label
        WattsGauge                      matlab.ui.control.Gauge
        WattsGaugeLabel                 matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        LeftPanel                       matlab.ui.container.Panel
        PulsesLabel                     matlab.ui.control.Label
        DutycycleLabel                  matlab.ui.control.Label
        EstISPPALabel                   matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        ProfilesTab                     matlab.ui.container.Tab
        ProfilesListBox                 matlab.ui.control.ListBox
        ProfilesListBoxLabel            matlab.ui.control.Label
        LoadprofileButton               matlab.ui.control.Button
        CurrentprofileEditField         matlab.ui.control.EditField
        CurrentprofileEditFieldLabel    matlab.ui.control.Label
        DeleteprofileButton             matlab.ui.control.Button
        SaveprofileButton               matlab.ui.control.Button
        ProtocolsTab                    matlab.ui.container.Tab
        ChooseButton                    matlab.ui.control.Button
        TriggerscriptLabel              matlab.ui.control.Label
        DelButton                       matlab.ui.control.Button
        AvailableprofilesListBox        matlab.ui.control.ListBox
        AvailableprofilesListBoxLabel   matlab.ui.control.Label
        AddButton                       matlab.ui.control.Button
        UpButton                        matlab.ui.control.Button
        DownButton                      matlab.ui.control.Button
        ProfilesinprotocolListBox       matlab.ui.control.ListBox
        ProfilesinprotocolListBoxLabel  matlab.ui.control.Label
        CurrentprotocolEditField        matlab.ui.control.EditField
        CurrentprotocolEditFieldLabel   matlab.ui.control.Label
        ProtocolsListBox                matlab.ui.control.ListBox
        ProtocolsListBoxLabel           matlab.ui.control.Label
        DeleteprotocolButton            matlab.ui.control.Button
        SaveprotocolButton              matlab.ui.control.Button
        LoadprotocolButton              matlab.ui.control.Button
        ExecuteselectedprotocolTab      matlab.ui.container.Tab
        CurrentprotocolLabel            matlab.ui.control.Label
        Profile0of0Label                matlab.ui.control.Label
        RestartprotocolButton           matlab.ui.control.Button
        PreviousButton                  matlab.ui.control.Button
        NextButton                      matlab.ui.control.Button
        ProfilesinprotocolListBox_2     matlab.ui.control.ListBox
        ProfilesinprotocolListBox_2Label  matlab.ui.control.Label
        FrequencyHzSpinner              matlab.ui.control.Spinner
        FrequencyHzSpinnerLabel         matlab.ui.control.Label
        GlobalpowerWSpinner             matlab.ui.control.Spinner
        GlobalpowerWSpinnerLabel        matlab.ui.control.Label
        BurstlengthmsSpinner            matlab.ui.control.Spinner
        BurstlengthmsSpinnerLabel       matlab.ui.control.Label
        BurstperiodmsSpinner            matlab.ui.control.Spinner
        BurstperiodmsSpinnerLabel       matlab.ui.control.Label
        TimermsSpinner                  matlab.ui.control.Spinner
        TimermsSpinnerLabel             matlab.ui.control.Label
        RampmodeDropDown                matlab.ui.control.DropDown
        RampmodeDropDownLabel           matlab.ui.control.Label
        RamplengthmsSpinner             matlab.ui.control.Spinner
        RamplengthmsSpinnerLabel        matlab.ui.control.Label
        FocusmmSpinner_2                matlab.ui.control.Spinner
        FocusmmSpinner_2Label           matlab.ui.control.Label
        FFLabel                         matlab.ui.control.Label
        PDLabel                         matlab.ui.control.Label
        SDLabel                         matlab.ui.control.Label
        PRFHzLabel                      matlab.ui.control.Label
        EnforcepowerlimitsCheckBox      matlab.ui.control.CheckBox
        ContextMenu                     matlab.ui.container.ContextMenu
        CopyMenu                        matlab.ui.container.Menu
    end

    properties (Access = public)
        s = []
        sonicating = false
        writingToTPO = false
        
        PD = 0.08
        PRF = 0
        SD = 0
        FF = 500
        rampLen = 0
        rampType = 'No ramping'
    end
    methods
        function set.rampLen(app,v)
            app.rampLen = v;
            app.updateTrace();
        end
        function set.rampType(app,v)
            app.rampType = v;
            app.updateTrace();
        end
        function set.PD(app,v)
            app.PD = v;
            app.updateTrace();
        end
        function set.PRF(app,v)
            app.PRF = v;
            app.updateTrace();
        end
        function set.SD(app,v)
            app.SD = v;
            app.updateTrace();
        end
        function set.FF(app,v)
            app.FF = v;
            app.updateTrace();
        end
        function v = get.sonicating(app)
            v = app.sonicating;
        end
        function set.sonicating(app,v)
            if v
                app.TimermsSpinner.Enable = "off";
                app.SonicatingLamp_2.Color = 'g';
                app.SonicatingLamp_2.Enable = "on";
                app.WattsGauge.Enable = "on";
                % app.WattsGauge.Value = app.GlobalpowerWSpinner.Value; % update this! to P_actual or ISPPA/ISPTA
                app.ConnectionidleLabel.Text = 'Currently sonicating';
            else
                app.SonicatingLamp_2.Color = 'r';
                app.SonicatingLamp_2.Enable = "off";
                app.TimermsSpinner.Enable = "on";
                app.WattsGauge.Enable = "off";
                % app.WattsGauge.Value = 0;
                app.ConnectionidleLabel.Text = 'Connection idle';
            end
            app.sonicating = v;
        end
    end
    methods (Access = private)
        
        function results = configureTUSC(app)
            initialize(app);
        end

         function attemptAutoMonitoringLoop(app)
            try
                value = app.StatusmonitoringSwitch.Value;
            catch
                value = "";
            end
            while value == "Auto"
                pause(0.001);
                app.readDeviceStatus();
                try
                    value = app.StatusmonitoringSwitch.Value;
                catch
                end
            end
         end

        function updateMetrics(app)
            dutyCycle = (app.BurstlengthmsSpinner.Value / app.BurstperiodmsSpinner.Value) * 100;
            dutyCycle = round(dutyCycle);
            try
                if ~isinf(dutyCycle)
                    app.DutycycleLabel.Text = sprintf('Duty cycle: %d%%',dutyCycle);
                else
                    app.DutycycleLabel.Text = sprintf('Duty cycle: ??%%',dutyCycle);
                end
            catch
            end

            try
                nPulses = app.TimermsSpinner.Value / app.BurstperiodmsSpinner.Value;
                if app.TimermsSpinner.Value == 0
                    nPulses = Inf;
                end
                % if ~isinf(nPulses)
                    app.PulsesLabel.Text = sprintf('Pulses: %.1f',nPulses);
                % else
                    % app.PulsesLabel.Text = sprintf('Pulses: ???');
                % end
            catch
            end
            if isinf(app.PRF)
                app.PRFHzLabel.Text = 'PRF (??? Hz)';
                return;
            end
            try
                app.PRFHzLabel.Text = sprintf('PRF (%.1f Hz)',app.PRF);
            catch
                app.PRFHzLabel.Text = 'PRF (??? Hz)';
            end

            if app.RampmodeDropDown.Value ~= "No ramping"
                app.EstISPPALabel.Text = 'Est. ISPPA: Unknown [ramping]';
            else
                [ispta,isppa,assumptions] = estimateISPTA(1,dutyCycle,'brain');
                app.EstISPPALabel.Text = sprintf('Est. ISPPA: %.2f',isppa);
                app.EstISPPALabel.Tooltip = assumptions;
            end
        end

        function updateTrace(app)
            ax = app.UIAxes;
            fs = max(app.FF * 20,2000);
            % app.FF
            % app.SD
            % app.PD
            % app.PRF
            updateMetrics(app)
            SDv = app.SD;
            isinfSD = false;
            if app.SD <= 0
                isinfSD = true;
                % SDv = 1000 * 2.5 * 1/app.PRF
                SDv = 1000 * 2 * 1/app.PRF;
                % offset = 0;
            end
            try
                sigduration = (SDv / 1000) * 2;
                % if app.SD > 0
                offset = sigduration/4;
                % end
                nsamples = round(sigduration * fs);
                if isinf(sigduration)
                    return;
                end
                sigtimes = linspace(0,sigduration,round(sigduration * fs)) - offset;
                xlim(ax,[sigtimes(1) sigtimes(end)]);
            catch
                return;
            end

            sig = zeros(1,nsamples);
            sdstartsamples = round(sigduration / 4 * fs) + 1;
            sdendsamples = round(fs * SDv / 1000) + sdstartsamples;
            sig(sdstartsamples:sdendsamples) = 1;
            
            hold(ax,'off');
            if isinfSD
                sig(1:end) = 1;
                sdtartsamples = 1;
                sdendsamples = length(sigtimes);
            end
            plot(ax,sigtimes,sig,'LineWidth',4); % plot sonication duration
            hold(ax,'on');

            burstPeriodSamples = round((1/app.PRF) * fs);
            burstStarts = [sdstartsamples:burstPeriodSamples:sdendsamples];
            newsig = sig;
            newsig(burstStarts) = -1;
            plot(ax,sigtimes,newsig,'LineWidth',2);

            burstEnds = [round(burstStarts + (app.PD/1000 * fs)), length(sigtimes)];

            burstStarts = burstStarts(burstStarts<=length(sigtimes));
            burstEnds = burstEnds(burstEnds<=length(sigtimes));

            try
            burstsig = zeros(1,nsamples);
            nBursts = min(length(burstStarts),length(burstEnds));
            for iBurst = 1:nBursts
                t = sigtimes(burstStarts(iBurst):(burstEnds(iBurst)-1));
                curWave = sin(t*2*pi*app.FF);
                burstsig(burstStarts(iBurst):(burstEnds(iBurst)-1)) = curWave;
            end

            startInds = burstStarts(1:nBursts);
            endInds = burstEnds(1:nBursts);

            outsig = burstsig.*sig;     

            outsig = app.apply_ramp(outsig, sigtimes, app.RamplengthmsSpinner.Value, app.RampmodeDropDown.Value, startInds, endInds);
            useAlpha = false;
            if ~isinfSD
                plot(ax,sigtimes,outsig,'k');
            else
                burstEnds(burstEnds>=length(sigtimes)) = [];
                plot(ax,sigtimes(1:burstEnds(end-1)),outsig(1:burstEnds(end-1)),'k'); % solid line
                c = [0,0,0];
                dX = round(fs / app.FF);
                inds = burstEnds(end-1):dX:(length(sigtimes)-1);
                if useAlpha
                    c(4) = 1;
                end
                for iSample = 1:(length(inds)-1)
                    % c = c + 1/(length(inds)+1);
                    if useAlpha
                        c(4) = c(4) - 1.5/(length(inds)+1);
                        if c(4) < 0
                            c(4) = 0;
                        end
                    else
                        c = c + 1.5/(length(inds)+1);
                        if c(1)>1
                            c = [1,1,1];
                        end
                    end
                    plot(ax,sigtimes(inds(iSample):inds(iSample+1)),outsig(inds(iSample):inds(iSample+1)),'Color',c);
                end
            end

            catch me
                me
                printstruct(me.stack)
            end

            % sigSamples = round(fs * (app.SD) * 1.5);
            % sigStartSamples = round(sigSamples / 6);
            % sigEndSamples = sigStartSamples + round(fs * app.SD / 1000);
            % 
            % sig = zeros(1,sigSamples);
            % sig((sigStartSamples+1):sigEndSamples) = 1;
            % sigTimes = linspace(0,(app.SD/1000)*1.5,sigSamples);
            % plot(ax,sigTimes,sig);
            % xlim(ax,[sigTimes(1) sigTimes(end)]);
        end

        function modified_signal = apply_ramp(app, signal, sample_times, ramp_duration, ramp_type, start_inds, end_inds)
            % Function to apply a ramp to the onset and offset of pulses in the signal.
            % Inputs:
            % - signal: Original sampled signal (vector)
            % - sample_times: Vector of sample times in milliseconds
            % - ramp_duration: Duration of the ramp in milliseconds
            % - ramp_type: Type of ramp to apply ('linear', 'tukey', 'logarithmic')

            if strcmpi(ramp_type,'No ramping')
                modified_signal = signal;
                return;
            end

            % Calculate the sampling interval
            dt = (sample_times(2) - sample_times(1)) * 1000;

            % Calculate the ramp length in samples
            ramp_length = round(ramp_duration / dt);

            is_pulse = zeros(size(sample_times));
            is_pulse(start_inds) = 1;
            is_pulse(end_inds) = -1;

            % % Calculate the envelope of the signal using Hilbert transform
            % analytic_signal = hilbert(signal);
            % envelope = abs(analytic_signal);

            % % Define a threshold as a fraction of the maximum envelope to detect pulses
            % threshold = 0.5 * max(envelope);

            % % Identify regions of the signal above the threshold
            % is_pulse = envelope > threshold;

            % Create the ramp function
            if strcmpi(ramp_type, 'linear')
                ramp_up = linspace(0, 1, ramp_length);
                ramp_down = linspace(1, 0, ramp_length);
            elseif strcmpi(ramp_type, 'tukey')
                alpha = 1; % Adjust the Tukey window parameter as needed
                ramp_up = tukeywin(ramp_length*2, alpha)';
                ramp_up = ramp_up(1:(length(ramp_up)/2));
                ramp_down = fliplr(ramp_up);
            elseif strcmpi(ramp_type, 'logarithmic')
                xs = (1:ramp_length)./ramp_length;
                ramp_up = normalize(log(xs),'range');
                ramp_down = fliplr(ramp_up);
            elseif strcmpi(ramp_type, 'exponential')
                ramp_up = logspace(-1, 0, ramp_length);
                ramp_down = logspace(0, -1, ramp_length);
            elseif strcmpi(ramp_type, 'gaussian')
                alpha = 3; % Adjust the Gaussian window parameter as needed
                ramp_up = gausswin(ramp_length*2, alpha)';
                ramp_up = ramp_up(1:(length(ramp_up)/2));
                ramp_down = fliplr(ramp_up);
            else
                % ramp_type
                error('Unsupported ramp type. Choose "linear", "tukey", or "logarithmic".');
            end

            % Initialize modified signal
            modified_signal = signal;

            pulseEdges = diff([0, is_pulse]);

            % rampBeginInds = find(pulseEdges==1)
            % rampEndInds = find(pulseEdges==-1)

            % Apply ramp to pulses
            for idx = 1:length(signal)
                % if is_pulse(idx)
                    % Check if we are at the beginning or end of a pulse
                    if pulseEdges(idx) == 1
                        % Apply ramp up at the beginning of a pulse
                        ramp_indices = idx:(idx + ramp_length - 1);
                        ramp_indices = ramp_indices(ramp_indices <= length(signal));
                        ramp_indices = ramp_indices(ramp_indices >= 1);
                        modified_signal(ramp_indices) = modified_signal(ramp_indices) .* ramp_up(1:length(ramp_indices));
                    elseif idx == length(signal) || pulseEdges(idx) == -1
                        % Apply ramp down at the end of a pulse
                        ramp_indices = (idx - ramp_length + 1):idx;
                        ramp_indices = ramp_indices(ramp_indices <= length(signal));
                        ramp_indices = ramp_indices(ramp_indices >= 1);
                        modified_signal(ramp_indices) = modified_signal(ramp_indices) .* ramp_down(1:length(ramp_indices));
                    end
                % end
            end
        end


        function readDeviceStatus(app)
            try
                if app.writingToTPO
                    return;
                end
                if ~isempty(app.s)
                    app.ConnectedLamp.Color = 'g';
                    app.ConnectedLamp.Enable = "on";
    
                        app.sonicating = logical(writeCheck(app.s,'SYSTEMSTATE?',[]));
                        app.BurstlengthmsSpinner.Value = writeCheck(app.s,'BURST?',[]);
                        app.BurstperiodmsSpinner.Value = writeCheck(app.s,'PERIOD?',[]);
                        if ~app.sonicating
                            app.TimermsSpinner.Value = writeCheck(app.s,'TIMER?',[]);
                        end
                        app.RampmodeDropDown.ValueIndex = writeCheck(app.s,'RAMPMODE?',[]) + 1;
                        app.RamplengthmsSpinner.Value = writeCheck(app.s,'RAMPLENGTH?',[]);
                        app.FocusmmSpinner_2.Value = writeCheck(app.s,'FOCUS?',[]);
                        app.GlobalpowerWSpinner.Value = writeCheck(app.s,'GLOBALPOWER?',[]);
                        app.EnforcepowerlimitsCheckBox.Value = logical(writeCheck(app.s,'ENFORCELIMITS?',[]));
                        app.TriggermodeSpinner.Value = writeCheck(app.s,'TRIGGERMODE?',[]);
                        app.SoundvolumeSpinner.Value = writeCheck(app.s,'VOLUME?',[]);
    
                        %writeCheck(app.s,'POWER?',[])
                        % = app.sendCommand(sprintf('OUTON?'),[]);
    
                else
                    app.ConnectedLamp.Color = 'r';
                    app.ConnectedLamp.Enable = "off";
                end
            catch
            end
        end

        function result = sendCommand(app,command,expectedOutput,status)
            if nargin < 4 || isempty(status)
                status = 'Sending command...';
            else
                status = [status,'...'];
            end
            logCommand(command);
            app.writingToTPO = true;
            result = [];
            app.ConnectionidleLabel.Text = status;
            if ~isempty(app.s)
                result = writeCheck(app.s,command,expectedOutput);
                if isempty(result)
                    status = 'Could not connect to TPO';
                else
                    if ~app.sonicating
                        status = 'Connection idle';
                    else
                        status = 'Currently sonicating';
                    end
                end
            else
                status = 'Could not connect to TPO';
            end
            app.ConnectionidleLabel.Text = status;
            app.writingToTPO = false;
            app.readDeviceStatus();
        end

        function updateProfileList(app)
            profs = dir('.\*.tuscprofile');
            items = {profs.name};
            items = cellfun(@(x) x(1:(find(x=='.',1,'last')-1)),items,'UniformOutput',false);
            app.ProfilesListBox.Items = items;
            app.AvailableprofilesListBox.Items = items;
        end

        function updateProtocolList(app)
            profs = dir('.\*.tuscproto');
            items = {profs.name};
            items = cellfun(@(x) x(1:(find(x=='.',1,'last')-1)),items,'UniformOutput',false);
            app.ProtocolsListBox.Items = items;
        end
        
        function s = openConnection(app)
            try
                freeports = serialportlist("available")
                % Open a serial connection at 115200 baud, 8 data bits, no parity, with
                % no handshaking:
                s = serialport(freeports(1),115200,"Parity","none","DataBits",8,"FlowControl","none",'Timeout',3);
                app.ConnectionidleLabel.Text = 'Attempting to connect to TPO...';
                configureTerminator(s,"CR");
    
                firmwareversion = s.readline();
                if ~isempty(firmwareversion)
                    fprintf('Successfully opened connection with device: %s\n',firmwareversion);
                    connstatus = 'Connected to TPO';
                    app.s = s;
                else
                    connstatus = 'Could not connect to TPO';
                    app.s = [];
                end
                pause(2);
                app.ConnectionidleLabel.Text = connstatus;
                app.readDeviceStatus();
                
                % % Configure TPO to accept advanced commands:
                sendCommand(app,'LOCAL=0','Local: 0');
                sendCommand(app,'VOLUME=0','Sound volume: 0','Setting volume');
            catch
            end
            % 
            % writeCheck(s,'TRIGGERMODE=0','Trigger Mode: 0');
            % 
            % % writeCheck(s,'BURST=180000','Burst length: 180.000 ms');
            % % writeCheck(s,'PERIOD=500000','Burst period: 500.000 ms');
            % writeCheck(s,'BURST=80','Burst length: 0.080 ms');
            % writeCheck(s,'PERIOD=20000','Burst period: 20.000 ms');
            % % writeCheck(s,'BURST=5000','Burst length: 5.000 ms');
            % 
            % % writeCheck(s,'GLOBALPOWER=500','Global power: 0.500');
            % 
            %     writeCheck(s,'TIMER=0','Timer: 0.000 ms'); % 0 constant for calibration
            % %     writeCheck(s,'TIMER=500000','Timer: 500.000 ms'); % 500ms for pulse
            % %     writeCheck(s,'RAMPMODE=4','Ramp mode: 4'); % exponential ramp
            %     writeCheck(s,'RAMPMODE=0','Ramp mode: 0'); % no ramp
            %     writeCheck(s,'RAMPLENGTH=4000','Ramp length: 4.000 ms'); % exponential ramp
            %     writeCheck(s,'FOCUS=41900','Focus: 41.900 mm'); % UNDOCUMENTED!!!!!!
            % % writeCheck(s,'FOCUS=39200','Focus: 39.200 mm'); % UNDOCUMENTED!!!!!!
            % %     writeCheck(s,'ENFORCELIMITS=0','Enforce Power Limits: 0'); % TURN OFF SAFETY!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!
            %     writeCheck(s,'ENFORCELIMITS=1','Enforce Power Limits: 1'); % TURN OFF SAFETY!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!
            % %      writeCheck(s,'GLOBALPOWER=25000','Global power: 25.000');
            % %     writeCheck(s,'GLOBALPOWER=10000','Global power: 10.000');
            % %     writeCheck(s,'GLOBALPOWER=21250','Global power: 21.250');
            %     writeCheck(s,'GLOBALPOWER=12690','Global power: 12.690');
            % 
            % %     writeCheck(s,'START','Treatment started');
            % %     writeCheck(s,'ABORT','Treatment aborted');
            % 
            % % Sanguinetti params:
            % 
            % % writeCheck(s,'PERIOD=120000000','Burst period: 120000.000 ms');
            % % writeCheck(s,'BURST=600000','Burst length: 600.000 ms');
            % % writeCheck(s,'GLOBALPOWER=4230','Global power: 4.230');
end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.configureTUSC;

            updateProfileList(app);
            updateProtocolList(app);
           
            updateTrace(app);

            s = app.openConnection()

            % app.StatusmonitoringSwitch.Value = "Auto";
            % app.StatusmonitoringSwitch.Value = "Manual";
            % app.StatusmonitoringSwitch.Value = "Auto";
            app.attemptAutoMonitoringLoop();
        end

        % Button pushed function: AboutButton
        function AboutButtonPushed(app, event)
            about_TUSC;
        end

        % Value changed function: ProfilesListBox
        function ProfilesListBoxValueChanged(app, event)
            %value = app.ProfilesListBox.Value;
        end

        % Button pushed function: LoadprofileButton
        function LoadprofileButtonPushed(app, event)
            app.CurrentprofileEditField.Value = app.ProfilesListBox.Value;

            filename = app.CurrentprofileEditField.Value;
            if isempty(filename)
                return;
            end

            curFile = strcat('./',filename,'.tuscprofile');

            loaded = load(curFile,'profiledata','-mat');
            profiledata = loaded.profiledata;
            
            app.FrequencyHzSpinner.Value = profiledata.app.FrequencyHzSpinner.Value;
            ev.Source = app.FrequencyHzSpinner;
            ev.PreviousValue = app.FrequencyHzSpinner.Value;
            app.FrequencyHzSpinnerValueChanged(ev);

            app.BurstlengthmsSpinner.Value = profiledata.app.BurstlengthmsSpinner.Value;
            ev.Source = app.BurstlengthmsSpinner;
            ev.PreviousValue = app.BurstlengthmsSpinner.Value;
            app.BurstlengthmsSpinnerValueChanged(ev);

            app.BurstperiodmsSpinner.Value = profiledata.app.BurstperiodmsSpinner.Value;
            ev.Source = app.BurstperiodmsSpinner;
            ev.PreviousValue = app.BurstperiodmsSpinner.Value;
            app.BurstperiodmsSpinnerValueChanged(ev);

            app.TimermsSpinner.Value = profiledata.app.TimermsSpinner.Value;
            ev.Source = app.TimermsSpinner;
            ev.PreviousValue = app.TimermsSpinner.Value;
            app.TimermsSpinnerValueChanged(ev);

            app.RampmodeDropDown.Value = profiledata.app.RampmodeDropDown.Value;
            ev.Source = app.RampmodeDropDown;
            ev.PreviousValue = app.RampmodeDropDown.Value;
            app.RampmodeDropDownValueChanged(ev);

            app.RamplengthmsSpinner.Value = profiledata.app.RamplengthmsSpinner.Value;
            ev.Source = app.RamplengthmsSpinner;
            ev.PreviousValue = app.RamplengthmsSpinner.Value;
            app.RamplengthmsSpinnerValueChanged(ev);

            app.FocusmmSpinner_2.Value = profiledata.app.FocusmmSpinner_2.Value;
            ev.Source = app.FocusmmSpinner_2;
            ev.PreviousValue = app.FocusmmSpinner_2.Value;
            app.FocusmmSpinner_2ValueChanged(ev);

            app.GlobalpowerWSpinner.Value = profiledata.app.GlobalpowerWSpinner.Value;
            ev.Source = app.GlobalpowerWSpinner;
            ev.PreviousValue = app.GlobalpowerWSpinner.Value;
            app.GlobalpowerWSpinnerValueChanged(ev);

            app.EnforcepowerlimitsCheckBox.Value = profiledata.app.EnforcepowerlimitsCheckBox.Value;
            
            app.updateTrace();
        end

        % Value changed function: BurstlengthmsSpinner
        function BurstlengthmsSpinnerValueChanged(app, event)

            value = app.BurstlengthmsSpinner.Value;
            result = app.sendCommand(sprintf('BURST=%d',value*1000),sprintf('Burst length: %3.3f ms',value));
            if strcmpi(result,'VALUE ERROR')
                event.Source.Value = event.PreviousValue;
            end
            app.PD = double(event.Source.Value);
        end

        % Value changed function: BurstperiodmsSpinner
        function BurstperiodmsSpinnerValueChanged(app, event)
            value = app.BurstperiodmsSpinner.Value;
            result = app.sendCommand(sprintf('PERIOD=%d',value*1000),sprintf('Burst period: %3.3f ms',value));
            if strcmpi(result,'VALUE ERROR')
                event.Source.Value = event.PreviousValue;
            end
            app.PRF = 1/(double(event.Source.Value)/1000);
        end

        % Value changed function: TimermsSpinner
        function TimermsSpinnerValueChanged(app, event)
            value = app.TimermsSpinner.Value;
            result = app.sendCommand(sprintf('TIMER=%d',value*1000),sprintf('Timer: %3.3f ms',value));
            if strcmpi(result,'VALUE ERROR')
                event.Source.Value = event.PreviousValue;
            end
            app.SD = double(event.Source.Value);
        end

        % Value changed function: RampmodeDropDown
        function RampmodeDropDownValueChanged(app, event)
            index = app.RampmodeDropDown.ValueIndex - 1;
            result = writeCheck(app.s,sprintf('RAMPMODE=%d',index),sprintf('Ramp mode: %d',index));
            app.rampType = event.Source.Value;
        end

        % Value changed function: RamplengthmsSpinner
        function RamplengthmsSpinnerValueChanged(app, event)
            value = app.RamplengthmsSpinner.Value;
            result = writeCheck(app.s,sprintf('RAMPLENGTH=%d',value*1000),sprintf('Ramp length: %d',value));
            if strcmpi(result,'VALUE ERROR')
                event.Source.Value = event.PreviousValue;
            end
            app.rampLen = event.Source.Value;
        end

        % Value changed function: FocusmmSpinner_2
        function FocusmmSpinner_2ValueChanged(app, event)
            value = app.FocusmmSpinner_2.Value;
        end

        % Value changed function: GlobalpowerWSpinner
        function GlobalpowerWSpinnerValueChanged(app, event)
            value = app.GlobalpowerWSpinner.Value;
            result = app.sendCommand(sprintf('GLOBALPOWER=%d',value*1000),sprintf('Global power: %3.3f',value));
            if strcmpi(result,'VALUE ERROR')
                event.Source.Value = event.PreviousValue;
            end
        end

        % Value changed function: EnforcepowerlimitsCheckBox
        function EnforcepowerlimitsCheckBoxValueChanged(app, event)
            event.Source.Enable = "off";
            value = app.EnforcepowerlimitsCheckBox.Value;
            if value == false
                app.LeftPanel.Enable = "off";
                app.CenterPanel.Enable = "off";
                app.RightPanel.Enable = "off";
                answer = questdlg('This will remove safety limits. Are you sure you wish to continue?', ...
	            'Warning','Yes','No, take me back','No, take me back');
                app.LeftPanel.Enable = "on";
                app.CenterPanel.Enable = "on";
                app.RightPanel.Enable = "on";
                if strcmpi(answer,'No, take me back')
                    event.Source.Value = event.PreviousValue;
                    event.Source.Enable = "on";
                    return;
                end
            end
            result = app.sendCommand(sprintf('ENFORCELIMITS=%d',double(value)),sprintf('Enforce Power Limits: %d',double(value)));
            event.Source.Enable = "on";
        end

        % Callback function
        function SonicateSwitchValueChanged(app, event)
            
        end

        % Value changed function: TriggermodeSpinner
        function TriggermodeSpinnerValueChanged(app, event)
            value = app.TriggermodeSpinner.Value;
            
        end

        % Value changed function: SoundvolumeSpinner
        function SoundvolumeSpinnerValueChanged(app, event)
            value = app.SoundvolumeSpinner.Value;
            
        end

        % Button pushed function: PanicButton
        function PanicButtonPushed(app, event)
            app.SonicateSwitch.Value = "Off";
            app.SonicateSwitch.ValueChangedFcn(app,[]);
        end

        % Button pushed function: SaveprofileButton
        function SaveprofileButtonPushed(app, event)
            filename = app.CurrentprofileEditField.Value;
            if isempty(filename)
                return;
            end
            curFile = strcat('./',filename,'.tuscprofile');
            
            profiledata = struct();
            profiledata.app.FrequencyHzSpinner.Value = app.FrequencyHzSpinner.Value;
            profiledata.app.BurstlengthmsSpinner.Value = app.BurstlengthmsSpinner.Value;
            profiledata.app.BurstperiodmsSpinner.Value = app.BurstperiodmsSpinner.Value;
            profiledata.app.TimermsSpinner.Value = app.TimermsSpinner.Value;
            profiledata.app.RampmodeDropDown.Value = app.RampmodeDropDown.Value;
            profiledata.app.RamplengthmsSpinner.Value = app.RamplengthmsSpinner.Value;
            profiledata.app.FocusmmSpinner_2.Value = app.FocusmmSpinner_2.Value;
            profiledata.app.GlobalpowerWSpinner.Value = app.GlobalpowerWSpinner.Value;
            profiledata.app.EnforcepowerlimitsCheckBox.Value = app.EnforcepowerlimitsCheckBox.Value;
            save(curFile,'profiledata','-mat');

            updateProfileList(app);
        end

        % Button pushed function: DeleteprofileButton
        function DeleteprofileButtonPushed(app, event)
            curFile = strcat('./',app.ProfilesListBox.Value,'.tuscprofile');
            try
                delete(curFile);
                app.ProfilesListBox.Items(app.ProfilesListBox.ValueIndex) = [];
            catch
            end
        end

        % Close request function: TUSCUIFigure
        function TUSCUIFigureCloseRequest(app, event)
            app.StatusmonitoringSwitch.Value = "Manual";
            delete(app)
        end

        % Value changed function: SonicateSwitch
        function SonicateSwitchValueChanged2(app, event)
            value = app.SonicateSwitch.Value;
            if strcmpi(value,"On")
                %app.sonicating = true;
                app.sendCommand('START','Treatment started');
            else
                app.sendCommand('ABORT','Treatment aborted');
                %app.sonicating = false;
            end
            app.readDeviceStatus();
            if app.sonicating
                app.WattsGauge.Value = app.GlobalpowerWSpinner.Value;
            else
                app.WattsGauge.Value = 0;
            end
        end

        % Value changed function: StatusmonitoringSwitch
        function StatusmonitoringSwitchValueChanged(app, event)
            if app.StatusmonitoringSwitch.Value == "Manual"
                app.RefreshfromdeviceButton.Enable = "on";
            else
                app.RefreshfromdeviceButton.Enable = "off";
            end
            app.attemptAutoMonitoringLoop();
        end

        % Button pushed function: RefreshfromdeviceButton
        function RefreshfromdeviceButtonPushed(app, event)
            app.readDeviceStatus();
        end

        % Value changed function: FrequencyHzSpinner
        function FrequencyHzSpinnerValueChanged(app, event)
            value = app.FrequencyHzSpinner.Value;
            
            % TODO: add in comms calls here
            
            app.FF = double(event.Source.Value);
        end

        % Menu selected function: CopyMenu
        function CopyMenuSelected(app, event)
            copygraphics(app.UIAxes)
        end

        % Button pushed function: LoadprotocolButton
        function LoadprotocolButtonPushed(app, event)
            app.CurrentprotocolEditField.Value = app.ProtocolsListBox.Value;

            filename = app.CurrentprotocolEditField.Value;
            if isempty(filename)
                return;
            end

            curFile = strcat('./',filename,'.tuscproto');

            loaded = load(curFile,'protocoldata','-mat');
            protocoldata = loaded.protocoldata;
            % printstruct(protocoldata)

            app.ProfilesinprotocolListBox.Items = [""];
            for iItem = 1:length(protocoldata.app.ProfilesinprotocolListBox.Items)
                 app.ProfilesinprotocolListBox.Items{iItem} = protocoldata.app.ProfilesinprotocolListBox.Items{iItem};
            end

            app.ProfilesinprotocolListBox_2.Items = [""];
            for iItem = 1:length(protocoldata.app.ProfilesinprotocolListBox.Items)
                 app.ProfilesinprotocolListBox_2.Items{iItem} = protocoldata.app.ProfilesinprotocolListBox.Items{iItem};
            end
            
            % app.ProfilesinprotocolListBox_2.Value = 1;

            % app.FrequencyHzSpinner.Value = profiledata.app.FrequencyHzSpinner.Value;
            % ev.Source = app.FrequencyHzSpinner;
            % ev.PreviousValue = app.FrequencyHzSpinner.Value;
            % app.FrequencyHzSpinnerValueChanged(ev);
            % 
            % app.BurstlengthmsSpinner.Value = profiledata.app.BurstlengthmsSpinner.Value;
            % ev.Source = app.BurstlengthmsSpinner;
            % ev.PreviousValue = app.BurstlengthmsSpinner.Value;
            % app.BurstlengthmsSpinnerValueChanged(ev);
            % 
            % app.BurstperiodmsSpinner.Value = profiledata.app.BurstperiodmsSpinner.Value;
            % ev.Source = app.BurstperiodmsSpinner;
            % ev.PreviousValue = app.BurstperiodmsSpinner.Value;
            % app.BurstperiodmsSpinnerValueChanged(ev);
            % 
            % app.TimermsSpinner.Value = profiledata.app.TimermsSpinner.Value;
            % ev.Source = app.TimermsSpinner;
            % ev.PreviousValue = app.TimermsSpinner.Value;
            % app.TimermsSpinnerValueChanged(ev);
            % 
            % app.RampmodeDropDown.Value = profiledata.app.RampmodeDropDown.Value;
            % ev.Source = app.RampmodeDropDown;
            % ev.PreviousValue = app.RampmodeDropDown.Value;
            % app.RampmodeDropDownValueChanged(ev);
            % 
            % app.RamplengthmsSpinner.Value = profiledata.app.RamplengthmsSpinner.Value;
            % ev.Source = app.RamplengthmsSpinner;
            % ev.PreviousValue = app.RamplengthmsSpinner.Value;
            % app.RamplengthmsSpinnerValueChanged(ev);
            % 
            % app.FocusmmSpinner_2.Value = profiledata.app.FocusmmSpinner_2.Value;
            % ev.Source = app.FocusmmSpinner_2;
            % ev.PreviousValue = app.FocusmmSpinner_2.Value;
            % app.FocusmmSpinner_2ValueChanged(ev);
            % 
            % app.GlobalpowerWSpinner.Value = profiledata.app.GlobalpowerWSpinner.Value;
            % ev.Source = app.GlobalpowerWSpinner;
            % ev.PreviousValue = app.GlobalpowerWSpinner.Value;
            % app.GlobalpowerWSpinnerValueChanged(ev);
            % 
            % app.EnforcepowerlimitsCheckBox.Value = profiledata.app.EnforcepowerlimitsCheckBox.Value;
            
            app.updateTrace();
        end

        % Button pushed function: SaveprotocolButton
        function SaveprotocolButtonPushed(app, event)
            filename = app.CurrentprotocolEditField.Value;
            if isempty(filename)
                return;
            end
            curFile = strcat('./',filename,'.tuscproto');
            
            protocoldata = struct();
            protocoldata.app.ProfilesinprotocolListBox.Items = app.ProfilesinprotocolListBox.Items;
            % protocoldata.app.FrequencyHzSpinner.Value = app.FrequencyHzSpinner.Value;
            % protocoldata.app.BurstlengthmsSpinner.Value = app.BurstlengthmsSpinner.Value;
            % protocoldata.app.BurstperiodmsSpinner.Value = app.BurstperiodmsSpinner.Value;
            % protocoldata.app.TimermsSpinner.Value = app.TimermsSpinner.Value;
            % protocoldata.app.RampmodeDropDown.Value = app.RampmodeDropDown.Value;
            % protocoldata.app.RamplengthmsSpinner.Value = app.RamplengthmsSpinner.Value;
            % protocoldata.app.FocusmmSpinner_2.Value = app.FocusmmSpinner_2.Value;
            % protocoldata.app.GlobalpowerWSpinner.Value = app.GlobalpowerWSpinner.Value;
            % protocoldata.app.EnforcepowerlimitsCheckBox.Value = app.EnforcepowerlimitsCheckBox.Value;
            save(curFile,'protocoldata','-mat');

            updateProtocolList(app);
        end

        % Button pushed function: DeleteprotocolButton
        function DeleteprotocolButtonPushed(app, event)
            curFile = strcat('./',app.ProtocolsListBox.Value,'.tuscproto');
            try
                delete(curFile);
                app.ProtocolsListBox.Items(app.ProtocolsListBox.ValueIndex) = [];
            catch
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            %curFile = strcat('./',app.ProtocolsListBox.Value,'.tuscproto');
            try
                %delete(curFile);
                % app.ProtocolsListBox.Items(app.ProtocolsListBox.ValueIndex) = [];
                app.ProfilesinprotocolListBox.Items(end+1) = app.AvailableprofilesListBox.Items(app.AvailableprofilesListBox.ValueIndex);
            catch
            end
        end

        % Button pushed function: DelButton
        function DelButtonPushed(app, event)
            app.ProfilesinprotocolListBox.Items(app.ProfilesinprotocolListBox.ValueIndex) = [];
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            % app.ProfilesinprotocolListBox.Items(app.ProfilesinprotocolListBox.ValueIndex) = [];
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            % ProfilesinprotocolListBox.Items(app.ProfilesinprotocolListBox.ValueIndex) = [];
        end

        % Button pushed function: ViewlogButton
        function ViewlogButtonPushed(app, event)
            TUSC_log;
        end

        % Button pushed function: ClearlogButton
        function ClearlogButtonPushed(app, event)
            newLog;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TUSCUIFigure = uifigure('Visible', 'off');
            app.TUSCUIFigure.Name = 'TUSC';
            app.TUSCUIFigure.Icon = fullfile(pathToMLAPP, 'resources', 'elephanticon_rcalmus130x130editedwbody.png');
            app.TUSCUIFigure.CloseRequestFcn = createCallbackFcn(app, @TUSCUIFigureCloseRequest, true);
            app.LeftPanel = uipanel(app.TUSCUIFigure);
            app.LeftPanel.Enable = 'off';
            app.EnforcepowerlimitsCheckBox = uicheckbox(app.LeftPanel);
            app.EnforcepowerlimitsCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnforcepowerlimitsCheckBoxValueChanged, true);
            app.EnforcepowerlimitsCheckBox.Text = 'Enforce power limits';
            app.EnforcepowerlimitsCheckBox.Value = true;
            app.PRFHzLabel = uilabel(app.LeftPanel);
            app.PRFHzLabel.Tooltip = {'Pulse repetition frequency (PRF)'};
            app.PRFHzLabel.Text = 'PRF (??? Hz)';
            app.SDLabel = uilabel(app.LeftPanel);
            app.SDLabel.Tooltip = {'Sonication duration (SD)'};
            app.SDLabel.Text = 'SD';
            app.PDLabel = uilabel(app.LeftPanel);
            app.PDLabel.Tooltip = {'Pulse duration (PD)'};
            app.PDLabel.Text = 'PD';
            app.FFLabel = uilabel(app.LeftPanel);
            app.FFLabel.Tooltip = {'Fundamental frequency (FF)'};
            app.FFLabel.Text = 'FF';
            app.FocusmmSpinner_2Label = uilabel(app.LeftPanel);
            app.FocusmmSpinner_2Label.Text = 'Focus (mm)';
            app.FocusmmSpinner_2 = uispinner(app.LeftPanel);
            app.FocusmmSpinner_2.Step = 0.5;
            app.FocusmmSpinner_2.ValueChangedFcn = createCallbackFcn(app, @FocusmmSpinner_2ValueChanged, true);
            app.RamplengthmsSpinnerLabel = uilabel(app.LeftPanel);
            app.RamplengthmsSpinnerLabel.Text = 'Ramp length (ms)';
            app.RamplengthmsSpinner = uispinner(app.LeftPanel);
            app.RamplengthmsSpinner.Step = 0.001;
            app.RamplengthmsSpinner.ValueDisplayFormat = '%.3f';
            app.RamplengthmsSpinner.ValueChangedFcn = createCallbackFcn(app, @RamplengthmsSpinnerValueChanged, true);
            app.RampmodeDropDownLabel = uilabel(app.LeftPanel);
            app.RampmodeDropDownLabel.Text = 'Ramp mode';
            app.RampmodeDropDown = uidropdown(app.LeftPanel);
            app.RampmodeDropDown.Items = {'No ramping', 'Linear', 'Tukey', 'Logarithmic', 'Exponential', 'Gaussian'};
            app.RampmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @RampmodeDropDownValueChanged, true);
            app.RampmodeDropDown.Value = 'No ramping';
            app.TimermsSpinnerLabel = uilabel(app.LeftPanel);
            app.TimermsSpinnerLabel.Text = 'Timer (ms)';
            app.TimermsSpinner = uispinner(app.LeftPanel);
            app.TimermsSpinner.ValueDisplayFormat = '%.3f';
            app.TimermsSpinner.ValueChangedFcn = createCallbackFcn(app, @TimermsSpinnerValueChanged, true);
            app.BurstperiodmsSpinnerLabel = uilabel(app.LeftPanel);
            app.BurstperiodmsSpinnerLabel.Text = 'Burst period (ms)';
            app.BurstperiodmsSpinner = uispinner(app.LeftPanel);
            app.BurstperiodmsSpinner.Step = 0.1;
            app.BurstperiodmsSpinner.ValueDisplayFormat = '%.3f';
            app.BurstperiodmsSpinner.ValueChangedFcn = createCallbackFcn(app, @BurstperiodmsSpinnerValueChanged, true);
            app.BurstlengthmsSpinnerLabel = uilabel(app.LeftPanel);
            app.BurstlengthmsSpinnerLabel.Text = 'Burst length (ms)';
            app.BurstlengthmsSpinner = uispinner(app.LeftPanel);
            app.BurstlengthmsSpinner.Step = 0.01;
            app.BurstlengthmsSpinner.ValueDisplayFormat = '%.3f';
            app.BurstlengthmsSpinner.ValueChangedFcn = createCallbackFcn(app, @BurstlengthmsSpinnerValueChanged, true);
            app.BurstlengthmsSpinner.Value = 0.08;
            app.GlobalpowerWSpinnerLabel = uilabel(app.LeftPanel);
            app.GlobalpowerWSpinnerLabel.Text = 'Global power (W)';
            app.GlobalpowerWSpinner = uispinner(app.LeftPanel);
            app.GlobalpowerWSpinner.Step = 0.1;
            app.GlobalpowerWSpinner.ValueDisplayFormat = '%.3f';
            app.GlobalpowerWSpinner.ValueChangedFcn = createCallbackFcn(app, @GlobalpowerWSpinnerValueChanged, true);
            app.FrequencyHzSpinnerLabel = uilabel(app.LeftPanel);
            app.FrequencyHzSpinnerLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzSpinner = uispinner(app.LeftPanel);
            app.FrequencyHzSpinner.Step = 0.1;
            app.FrequencyHzSpinner.ValueDisplayFormat = '%.3f';
            app.FrequencyHzSpinner.ValueChangedFcn = createCallbackFcn(app, @FrequencyHzSpinnerValueChanged, true);
            app.FrequencyHzSpinner.Value = 500;
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.ProfilesTab = uitab(app.TabGroup);
            app.ProfilesTab.Title = 'Profiles';
            app.SaveprofileButton = uibutton(app.ProfilesTab, 'push');
            app.SaveprofileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveprofileButtonPushed, true);
            app.SaveprofileButton.Text = 'Save profile';
            app.DeleteprofileButton = uibutton(app.ProfilesTab, 'push');
            app.DeleteprofileButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteprofileButtonPushed, true);
            app.DeleteprofileButton.Text = 'Delete profile';
            app.CurrentprofileEditFieldLabel = uilabel(app.ProfilesTab);
            app.CurrentprofileEditFieldLabel.Text = 'Current profile:';
            app.CurrentprofileEditField = uieditfield(app.ProfilesTab, 'text');
            app.LoadprofileButton = uibutton(app.ProfilesTab, 'push');
            app.LoadprofileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadprofileButtonPushed, true);
            app.LoadprofileButton.Text = 'Load profile';
            app.ProfilesListBoxLabel = uilabel(app.ProfilesTab);
            app.ProfilesListBoxLabel.Text = 'Profiles:';
            app.ProfilesListBox = uilistbox(app.ProfilesTab);
            app.ProfilesListBox.Items = {' '};
            app.ProfilesListBox.ValueChangedFcn = createCallbackFcn(app, @ProfilesListBoxValueChanged, true);
            app.ProfilesListBox.Value = ' ';
            app.ProtocolsTab = uitab(app.TabGroup);
            app.ProtocolsTab.Title = 'Protocols';
            app.LoadprotocolButton = uibutton(app.ProtocolsTab, 'push');
            app.LoadprotocolButton.ButtonPushedFcn = createCallbackFcn(app, @LoadprotocolButtonPushed, true);
            app.LoadprotocolButton.Text = 'Load protocol';
            app.SaveprotocolButton = uibutton(app.ProtocolsTab, 'push');
            app.SaveprotocolButton.ButtonPushedFcn = createCallbackFcn(app, @SaveprotocolButtonPushed, true);
            app.SaveprotocolButton.Text = 'Save protocol';
            app.DeleteprotocolButton = uibutton(app.ProtocolsTab, 'push');
            app.DeleteprotocolButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteprotocolButtonPushed, true);
            app.DeleteprotocolButton.Text = 'Delete protocol';
            app.ProtocolsListBoxLabel = uilabel(app.ProtocolsTab);
            app.ProtocolsListBoxLabel.Text = 'Protocols:';
            app.ProtocolsListBox = uilistbox(app.ProtocolsTab);
            app.ProtocolsListBox.Items = {' '};
            app.ProtocolsListBox.Value = ' ';
            app.CurrentprotocolEditFieldLabel = uilabel(app.ProtocolsTab);
            app.CurrentprotocolEditFieldLabel.Text = 'Current protocol:';
            app.CurrentprotocolEditField = uieditfield(app.ProtocolsTab, 'text');
            app.ProfilesinprotocolListBoxLabel = uilabel(app.ProtocolsTab);
            app.ProfilesinprotocolListBoxLabel.Text = 'Profiles in protocol:';
            app.ProfilesinprotocolListBox = uilistbox(app.ProtocolsTab);
            app.ProfilesinprotocolListBox.Items = {};
            app.ProfilesinprotocolListBox.Value = {};
            app.DownButton = uibutton(app.ProtocolsTab, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down 🢃';
            app.UpButton = uibutton(app.ProtocolsTab, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = '🢁 Up';
            app.AddButton = uibutton(app.ProtocolsTab, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add >>';
            app.AvailableprofilesListBoxLabel = uilabel(app.ProtocolsTab);
            app.AvailableprofilesListBoxLabel.Text = 'Available profiles:';
            app.AvailableprofilesListBox = uilistbox(app.ProtocolsTab);
            app.AvailableprofilesListBox.Items = {' '};
            app.AvailableprofilesListBox.Value = ' ';
            app.DelButton = uibutton(app.ProtocolsTab, 'push');
            app.DelButton.ButtonPushedFcn = createCallbackFcn(app, @DelButtonPushed, true);
            app.DelButton.Text = '<< Del';
            app.TriggerscriptLabel = uilabel(app.ProtocolsTab);
            app.TriggerscriptLabel.Text = 'Trigger script:';
            app.ChooseButton = uibutton(app.ProtocolsTab, 'push');
            app.ChooseButton.Text = 'Choose...';
            app.ExecuteselectedprotocolTab = uitab(app.TabGroup);
            app.ExecuteselectedprotocolTab.Title = 'Execute selected protocol';
            app.ProfilesinprotocolListBox_2Label = uilabel(app.ExecuteselectedprotocolTab);
            app.ProfilesinprotocolListBox_2Label.Text = 'Profiles in protocol:';
            app.ProfilesinprotocolListBox_2 = uilistbox(app.ExecuteselectedprotocolTab);
            app.ProfilesinprotocolListBox_2.Items = {' '};
            app.ProfilesinprotocolListBox_2.Value = ' ';
            app.NextButton = uibutton(app.ExecuteselectedprotocolTab, 'push');
            app.NextButton.Text = 'Next 🡆';
            app.PreviousButton = uibutton(app.ExecuteselectedprotocolTab, 'push');
            app.PreviousButton.Text = '🡄 Previous';
            app.RestartprotocolButton = uibutton(app.ExecuteselectedprotocolTab, 'push');
            app.RestartprotocolButton.Text = 'Restart protocol';
            app.Profile0of0Label = uilabel(app.ExecuteselectedprotocolTab);
            app.Profile0of0Label.Text = 'Profile 0 of 0';
            app.CurrentprotocolLabel = uilabel(app.ExecuteselectedprotocolTab);
            app.CurrentprotocolLabel.Text = 'Current protocol';
            app.EstISPPALabel = uilabel(app.LeftPanel);
            app.EstISPPALabel.Text = 'Est. ISPPA:';
            app.DutycycleLabel = uilabel(app.LeftPanel);
            app.DutycycleLabel.Text = 'Duty cycle:';
            app.PulsesLabel = uilabel(app.LeftPanel);
            app.PulsesLabel.Text = 'Pulses: ';
            app.CenterPanel = uipanel(app.TUSCUIFigure);
            app.CenterPanel.Enable = 'off';
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Approximate simulated output')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, {'Normalized'; 'intensity'})
            app.WattsGaugeLabel = uilabel(app.CenterPanel);
            app.WattsGaugeLabel.Enable = 'off';
            app.WattsGaugeLabel.Text = 'Watts';
            app.WattsGauge = uigauge(app.CenterPanel, 'circular');
            app.WattsGauge.Enable = 'off';
            app.ConnectedLampLabel = uilabel(app.CenterPanel);
            app.ConnectedLampLabel.Text = 'Connected';
            app.ConnectedLamp = uilamp(app.CenterPanel);
            app.ConnectedLamp.Enable = 'off';
            app.SonicateSwitchLabel = uilabel(app.CenterPanel);
            app.SonicateSwitchLabel.Text = 'Sonicate';
            app.TUSCLabel = uilabel(app.CenterPanel);
            app.TUSCLabel.Text = 'TUSC';
            app.TranscranialUltrasoundControlLabel = uilabel(app.CenterPanel);
            app.TranscranialUltrasoundControlLabel.Text = 'Transcranial Ultrasound Control';
            app.SonicateSwitch = uiswitch(app.CenterPanel, 'slider');
            app.SonicateSwitch.ValueChangedFcn = createCallbackFcn(app, @SonicateSwitchValueChanged2, true);
            app.SonicatingLamp_2Label = uilabel(app.CenterPanel);
            app.SonicatingLamp_2Label.Text = 'Sonicating';
            app.Label = uilabel(app.CenterPanel);
            app.Label.Text = '________________________________________________';
            app.SonicatingLamp_2 = uilamp(app.CenterPanel);
            app.SonicatingLamp_2.Enable = 'off';
            app.StatusPanel = uipanel(app.CenterPanel);
            app.StatusPanel.Title = 'Status';
            app.ConnectionidleLabel = uilabel(app.StatusPanel);
            app.ConnectionidleLabel.Text = 'Connection idle';
            app.ButtonGroup_2 = uibuttongroup(app.CenterPanel);
            app.InfButton_3 = uitogglebutton(app.ButtonGroup_2);
            app.InfButton_3.Text = 'Inf';
            app.InfButton_3.Value = true;
            app.xButton_2 = uitogglebutton(app.ButtonGroup_2);
            app.xButton_2.Text = '1x';
            app.Button_5 = uitogglebutton(app.ButtonGroup_2);
            app.Button_5.Text = '-';
            app.Button_6 = uitogglebutton(app.ButtonGroup_2);
            app.Button_6.Text = '+';
            app.RightPanel = uipanel(app.TUSCUIFigure);
            app.RightPanel.Enable = 'off';
            app.AboutButton = uibutton(app.RightPanel, 'push');
            app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButtonPushed, true);
            app.AboutButton.Text = 'About';
            app.TriggermodeSpinnerLabel = uilabel(app.RightPanel);
            app.TriggermodeSpinnerLabel.Text = 'Trigger mode';
            app.TriggermodeSpinner = uispinner(app.RightPanel);
            app.TriggermodeSpinner.ValueChangedFcn = createCallbackFcn(app, @TriggermodeSpinnerValueChanged, true);
            app.SoundvolumeSpinnerLabel = uilabel(app.RightPanel);
            app.SoundvolumeSpinnerLabel.Text = 'Sound volume';
            app.SoundvolumeSpinner = uispinner(app.RightPanel);
            app.SoundvolumeSpinner.ValueChangedFcn = createCallbackFcn(app, @SoundvolumeSpinnerValueChanged, true);
            app.PanicButton = uibutton(app.RightPanel, 'push');
            app.PanicButton.ButtonPushedFcn = createCallbackFcn(app, @PanicButtonPushed, true);
            app.PanicButton.Text = 'Panic';
            app.StatusmonitoringSwitchLabel = uilabel(app.RightPanel);
            app.StatusmonitoringSwitchLabel.Text = 'Status monitoring';
            app.StatusmonitoringSwitch = uiswitch(app.RightPanel, 'slider');
            app.StatusmonitoringSwitch.Items = {'Auto', 'Manual'};
            app.StatusmonitoringSwitch.ValueChangedFcn = createCallbackFcn(app, @StatusmonitoringSwitchValueChanged, true);
            app.StatusmonitoringSwitch.Value = 'Auto';
            app.RefreshfromdeviceButton = uibutton(app.RightPanel, 'push');
            app.RefreshfromdeviceButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshfromdeviceButtonPushed, true);
            app.RefreshfromdeviceButton.Enable = 'off';
            app.RefreshfromdeviceButton.Text = 'Refresh from device';
            app.ClearlogButton = uibutton(app.RightPanel, 'push');
            app.ClearlogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearlogButtonPushed, true);
            app.ClearlogButton.Text = 'Clear log';
            app.ViewlogButton = uibutton(app.RightPanel, 'push');
            app.ViewlogButton.ButtonPushedFcn = createCallbackFcn(app, @ViewlogButtonPushed, true);
            app.ViewlogButton.Text = 'View log';
            app.ContextMenu = uicontextmenu(app.TUSCUIFigure);
            app.CopyMenu = uimenu(app.ContextMenu);
            app.CopyMenu.MenuSelectedFcn = createCallbackFcn(app, @CopyMenuSelected, true);
            app.CopyMenu.Accelerator = 'C';
            app.CopyMenu.Text = '&Copy';
            % Assign app.ContextMenu
            app.UIAxes.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.TUSCUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TUSC

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TUSCUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.TUSCUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TUSCUIFigure)
        end
    end
end